classdef HMI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SlpEditField_4                 matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        Gauge                          matlab.ui.control.Gauge
        Gauge2                         matlab.ui.control.Gauge
        Label                          matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Lamp_3Label                    matlab.ui.control.Label
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2Label                    matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_7                         matlab.ui.control.Lamp
        PButton                        matlab.ui.control.Button
        PressurebarKnobLabel           matlab.ui.control.Label
        PressurebarKnob                matlab.ui.control.Knob
        SlopeEditFieldLabel            matlab.ui.control.Label
        SlopeEditField                 matlab.ui.control.NumericEditField
        VoltageEditFieldLabel          matlab.ui.control.Label
        VoltageEditField               matlab.ui.control.NumericEditField
        ACT_INITREditFieldLabel        matlab.ui.control.Label
        ACT_INITREditField             matlab.ui.control.NumericEditField
        ACT_INITLEditFieldLabel        matlab.ui.control.Label
        ACT_INITLEditField             matlab.ui.control.NumericEditField
        Lamp_10Label                   matlab.ui.control.Label
        Lamp_10                        matlab.ui.control.Lamp
        Lamp_11Label                   matlab.ui.control.Label
        Lamp_11                        matlab.ui.control.Lamp
        SimTimeEditFieldLabel          matlab.ui.control.Label
        SimTimeEditField               matlab.ui.control.NumericEditField
        SIButton                       matlab.ui.control.StateButton
        Knob                           matlab.ui.control.Knob
        Accelerationms2Label           matlab.ui.control.Label
        ReleaseButton                  matlab.ui.control.StateButton
        ApplyButton                    matlab.ui.control.Button
        FaultySignalsLabel             matlab.ui.control.Label
        Label_26                       matlab.ui.control.Label
        Lamp_12                        matlab.ui.control.Lamp
        Lamp_15Label                   matlab.ui.control.Label
        Lamp_15                        matlab.ui.control.Lamp
        Lamp_16Label                   matlab.ui.control.Label
        Lamp_16                        matlab.ui.control.Lamp
        Lamp_17Label                   matlab.ui.control.Label
        Lamp_17                        matlab.ui.control.Lamp
        Lamp_18Label                   matlab.ui.control.Label
        Lamp_18                        matlab.ui.control.Lamp
        Lamp_19Label                   matlab.ui.control.Label
        Lamp_19                        matlab.ui.control.Lamp
        Lamp_14Label                   matlab.ui.control.Label
        Lamp_14                        matlab.ui.control.Lamp
        Lamp_13Label                   matlab.ui.control.Label
        Lamp_13                        matlab.ui.control.Lamp
        Lamp_20Label                   matlab.ui.control.Label
        Lamp_20                        matlab.ui.control.Lamp
        Label_27                       matlab.ui.control.Label
        Lamp_21                        matlab.ui.control.Lamp
        Label_28                       matlab.ui.control.Label
        Lamp_22                        matlab.ui.control.Lamp
        Label_32                       matlab.ui.control.Label
        Lamp_23                        matlab.ui.control.Lamp
        Label_33                       matlab.ui.control.Label
        Lamp_24                        matlab.ui.control.Lamp
        Label_34                       matlab.ui.control.Label
        Lamp_25                        matlab.ui.control.Lamp
        Label_36                       matlab.ui.control.Label
        Lamp_27                        matlab.ui.control.Lamp
        Label_37                       matlab.ui.control.Label
        Lamp_28                        matlab.ui.control.Lamp
        Label_38                       matlab.ui.control.Label
        Lamp_29                        matlab.ui.control.Lamp
        Label_35                       matlab.ui.control.Label
        Lamp_26                        matlab.ui.control.Lamp
        Label_29                       matlab.ui.control.Label
        Lamp_30                        matlab.ui.control.Lamp
        Lamp_35Label                   matlab.ui.control.Label
        Lamp_35                        matlab.ui.control.Lamp
        Lamp_34Label                   matlab.ui.control.Label
        Lamp_34                        matlab.ui.control.Lamp
        Lamp_32Label                   matlab.ui.control.Label
        Lamp_32                        matlab.ui.control.Lamp
        Lamp_31Label                   matlab.ui.control.Label
        Lamp_31                        matlab.ui.control.Lamp
        Lamp_36Label                   matlab.ui.control.Label
        Lamp_36                        matlab.ui.control.Lamp
        Lamp_37Label                   matlab.ui.control.Label
        Lamp_37                        matlab.ui.control.Lamp
        Lamp_38Label                   matlab.ui.control.Label
        Lamp_38                        matlab.ui.control.Lamp
        Lamp_33Label                   matlab.ui.control.Label
        Lamp_33                        matlab.ui.control.Lamp
        Label_30                       matlab.ui.control.Label
        Lamp_39                        matlab.ui.control.Lamp
        Lamp_46Label                   matlab.ui.control.Label
        Lamp_46                        matlab.ui.control.Lamp
        Lamp_47Label                   matlab.ui.control.Label
        Lamp_47                        matlab.ui.control.Lamp
        Lamp_45Label                   matlab.ui.control.Label
        Lamp_45                        matlab.ui.control.Lamp
        Lamp_40Label                   matlab.ui.control.Label
        Lamp_40                        matlab.ui.control.Lamp
        Lamp_41Label                   matlab.ui.control.Label
        Lamp_41                        matlab.ui.control.Lamp
        Lamp_42Label                   matlab.ui.control.Label
        Lamp_42                        matlab.ui.control.Lamp
        Label_31                       matlab.ui.control.Label
        Lamp_43                        matlab.ui.control.Lamp
        Lamp_44Label                   matlab.ui.control.Label
        Lamp_44                        matlab.ui.control.Lamp
        Image2                         matlab.ui.control.Image
        HstAvail_LEditFieldLabel       matlab.ui.control.Label
        HstAvail_LEditField            matlab.ui.control.NumericEditField
        HstAvail_REditFieldLabel       matlab.ui.control.Label
        HstAvail_REditField            matlab.ui.control.NumericEditField
        KMOTEditFieldLabel             matlab.ui.control.Label
        KMOTEditField                  matlab.ui.control.NumericEditField
        PBCStateLabel                  matlab.ui.control.Label
        Switch_3                       matlab.ui.control.ToggleSwitch
        PlotGraphLabel                 matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        Switch_4                       matlab.ui.control.RockerSwitch
        FaultsinjectionEditFieldLabel  matlab.ui.control.Label
        FaultsinjectionEditField       matlab.ui.control.EditField
        TestmodeLabel                  matlab.ui.control.Label
        RMOTEditFieldLabel             matlab.ui.control.Label
        RMOTEditField                  matlab.ui.control.NumericEditField
        HPSDELAYEditFieldLabel         matlab.ui.control.Label
        HPSDELAYEditField              matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel      matlab.ui.control.Label
        TemperatureEditField           matlab.ui.control.NumericEditField
        ReleaseLimitEditFieldLabel     matlab.ui.control.Label
        ReleaseLimitEditField          matlab.ui.control.NumericEditField
        CurrTimeEditFieldLabel         matlab.ui.control.Label
        CurrTimeEditField              matlab.ui.control.NumericEditField
        UButton                        matlab.ui.control.Button
        UpdateParamLabel               matlab.ui.control.Label
        timeButton                     matlab.ui.control.Button
        FreeTravelLEditFieldLabel      matlab.ui.control.Label
        FreeTravelLEditField           matlab.ui.control.NumericEditField
        FreeTravelREditFieldLabel      matlab.ui.control.Label
        FreeTravelREditField           matlab.ui.control.NumericEditField
        SButton                        matlab.ui.control.Button
        StopLabel                      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
        function [r]= func(ret,app)
              %APPRELEASE=evalin('base','APPRELEASE');
             token = strtok(app.PBCStateLabel.Text,':')
              
             
             if ret==113
                   
                     
                       str= append(token,":Applying");
             disp(str)

                       app.PBCStateLabel.Text=str;
                       
               elseif ret==27
                   
                     
                       str=append (token,":Applied");
                                            disp(str)

                       app.PBCStateLabel.Text=str;

               elseif ret==135
                   
                    
                     str=append (token,":Releasing");
                                          disp(str)

                     app.PBCStateLabel.Text=str;
                     
               elseif ret==54
                   
                      
                     str= append (token,":Released");
                     disp(str)
                      app.PBCStateLabel.Text=str;
               elseif ret==66
                   
                      
                     str= append (token,":Dynamic");
                     disp(str)
                      app.PBCStateLabel.Text=str;
               
               end
               
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                 movegui(app.UIFigure,'center');
                 app.UIAxes.Title.Color='0,0,0';
                 app.UIAxes2.Title.Color='0,0,0';

                 Model='APB_Model0_v03_Test';
                 load("workspace.mat")
                 app.SlopeEditField.Value=phys_RdEnvt_Slope;
              
                 evalin('base','Data_IntegDRYJPN3');
                 evalin('base','startupNew_2');
                 app.PressurebarKnob.Value=evalin('base','calb_Driver_Prss');
                 app.Knob.Value=evalin('base','calb_Driver_acce');
                 assignin("base",'calb_Driver_bttn',0);
                 app.HstAvail_LEditField.Value=evalin('base','calb_HWDrCt_HstAvL');
                 app.HstAvail_REditField.Value=evalin('base','calb_HWDrCt_HstAvR');
              
                 app.ACT_INITREditField.Value=calb_PdsFcn_DSR9;
                 app.ACT_INITLEditField.Value=calb_PdsFcn_DSR1;
                 app.VoltageEditField.Value=phys_PwrNet_V_In;
                 app.RMOTEditField.Value=calb_ActMtr_RMotR;
                 app.KMOTEditField.Value=calb_ActMtr_KMotL
                 app.ReleaseLimitEditField.Value=ReleaseLimit;
                 app.TemperatureEditField.Value=calb_VehBhv_Temp
            
        end

        % Callback function
        function StartstopSimulationButtonPushed(app, event)


        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)


               if app.SimTimeEditField.Value==0
              f = msgbox("Sim time Invalid Value","Error","error");
           
              else
                  
                  
           if strcmp('Left/Right',app.Switch_3.Value)
              
              
                  courant=evalin('base','CurrOutL');
                 
                 VoltagOut=evalin('base','VoltagOutL');
                 Clamp=evalin('base','ClampL');
           
           
           else


               courant=evalin('base','CurrOutR');
                 VoltagOut=evalin('base','VoltagOutR');
                 Clamp=evalin('base','ClampR');
           end
           
       
           cla(app.UIAxes);
           app.UIAxes.XLim=[0 app.SimTimeEditField.Value]; 
           app.UIAxes2.XLim=[0 app.SimTimeEditField.Value]; 


         hold(app.UIAxes,'on') ;
         plot(app.UIAxes,VoltagOut.Time,VoltagOut.Data(:,1),'-'); 
       
         plot(app.UIAxes,courant.Time,courant.Data(:,1),'-');
         legend(app.UIAxes,'VoltageIn(V)','CurrentIn(A)');
        
         hold(app.UIAxes,'off')
         plot(app.UIAxes2,Clamp.Time,Clamp.Data(:,1),'-');
        
        end
        
           
        end

        % Value changed function: SIButton
        function SIButtonValueChanged(app, event)
         
                 
                 app.Lamp_11.Enable=true;
                 app.Lamp.Enable=true;
                 pause(0.8);
                 i=0;
                 app.Lamp_10.Enable=true;
                 app.Lamp_3.Enable=true;
              
                 while (app.Gauge.Value<100)
                    i=i+0.5
                    app.Gauge2.Value= app.Gauge2.Value+i;
                    app.Gauge.Value= app.Gauge.Value+i;
                    pause(0.09);
                 end
                
                 app.Lamp_2.Enable=true;
                 app.Lamp_7.Enable=true;
                  while (app.Gauge.Value>10)
                    i=i+0.5
                    app.Gauge2.Value= app.Gauge2.Value-i;
                    app.Gauge.Value= app.Gauge.Value-i;
                    pause(0.05);
                 end
            
            if app.SimTimeEditField.Value==0
              f = msgbox("Sim time Invalid Value","Error","error");
           
           else
         
           
            app.UIAxes.XLim=[0 app.SimTimeEditField.Value];     

            app.UIAxes2.XLim=[0 app.SimTimeEditField.Value];     


            %% Instru Test mode avant la simulation......


         instru= char(app.FaultsinjectionEditField.Value);
         n=strlength(instru);
         idx=strfind(instru,",");
         digit=isstrprop(instru,"digit");
         Letters=isstrprop(instru,"alpha");
         Sd=sum(digit,'all');
         Sl=sum(Letters,'all');
         
             a= extractBefore(instru,',');
             a=str2double(a)
             b= extractAfter(instru,',');
             b=str2double(b)
            
             z=a
             disp(a)
             disp(b)
             disp(z)
        
             if ~strcmp('Off',app.Switch_4.Value)


         if idx~=2 & idx ~=3 | numel(idx)==0
          
               f = msgbox("Please put a comma between inputs","Error","error");
       
         elseif isnan(a) | a>40
             
          msgbox("There's only 40 errors, please choose betwen 1 and 40","Error","error");
          
         elseif Sl>0   
             f = msgbox("No character should be used","Error","error");
             
        elseif isnan(b) | b>1000
              msgbox("We can only assign a value between 1 and 1000","Error","error");
         else


                    switch  z
                    case 1
                        %disp('1') 
                     
                      
                        app.Lamp_13.Enable=true;
                    case 2
                        disp('2')
                       
                        app.Lamp_14.Enable=true;
                    case 3
                        disp('3')
                   
                    
                    case 4
                        disp('4')
                       
                        app.Lamp_15.Enable=true;
                    case 5
                       
                        disp('5')
                        app.Lamp_16.Enable=true;
                    case 6
                        
                        disp('6')
                        app.Lamp_17.Enable=true;
                    case 7
                    
                        disp('7')
                        app.Lamp_18.Enable=true;
                    case 8
                     
                        disp('8')
                        app.Lamp_19.Enable=true;
                    case 9
                    
                        disp('9')
                        app.Lamp_20.Enable=true;
                    case 10
                      
                        disp('10')
                        app.Lamp_21.Enable=true;
                    case 11
                      
                        disp('11')
                        app.Lamp_23.Enable=true;
                    case 12
                       
                        disp('12')
                        app.Lamp_24.Enable=true;
                    case 13
                        
                        disp('13')
                        app.Lamp_25.Enable=true;
                    case 14
                        
                        disp('14')
                        app.Lamp_26.Enable=true;
                    case 15
                         
                      
                        disp('15')
                        app.Lamp_27.Enable=true;
                    case 16
                         
                  
                        disp('16')
                        app.Lamp_28.Enable=true;
                    case 17
                        
                       
                        disp('17')
                        app.Lamp_29.Enable=true;
                    case 18
                          
                      
                        disp('18')
                        app.Lamp_22.Enable=true;
                    case 19
                          
                        app.Lamp_38.Enable=true;
                        disp('19')
                    case 20
                       
                         app.Lamp_37.Enable=true;
                        disp('20')
                    case 21
                        
                       app.Lamp_36.Enable=true;
                        disp('21')
                    case 22
                        
                          app.Lamp_30.Enable=true;
                        disp('22')
                    case 23
                            app.Lamp_35.Enable=true;
                        disp('23')
                    case 24
                         app.Lamp_34.Enable=true;
                        disp('24')
                    case 25
                         app.Lamp_33.Enable=true;
                        disp('25')
                    case 26
                         app.Lamp_32.Enable=true;
                        disp('26')
                    case 27
                         app.Lamp_31.Enable=true;
                        disp('27')
                        
                    case 28
                         app.Lamp_47.Enable=true;
               
                        disp('28')
                    case 29
                         app.Lamp_46.Enable=true
                        disp('29')
                    case 30
                         app.Lamp_45.Enable=true
                        disp('30')
                    case 31
                         app.Lamp_39.Enable=true
                        disp('31')
                    case 32
                         app.Lamp_44.Enable=true
                        disp('32')
                    case 33
                         app.Lamp_43.Enable=true
                        disp('33')
                    case 34
                         app.Lamp_42.Enable=true
                        disp('34')
                    case 35
                         app.Lamp_41.Enable=true
                        disp('35')
                    case 36
                         app.Lamp_40.Enable=true
                        disp('36')
                    case 37
                        % app.Lamp_31.Enable=true
                        disp('37')
                    case 38
                        % app.Lamp_31.Enable=true
                        disp('38')
                    case 39
                        % app.Lamp_31.Enable=true
                        disp('39')
               otherwise
                        disp('nothing')
                        
           end 
         end
             else


               Model='APB_Model0_v03_Test';
               mdl = Simulink.SimulationInput(Model);


               simIn = setModelParameter(mdl,'StopTime',app.SimTimeEditField.Value);
               out = sim(simIn.ModelName,app.SimTimeEditField.Value);


               assignin("base",'VoltagOutL', out.phys_ActHbdg_PBCVOLIn_L);
               assignin("base",'CurrOutL',timeseries(out.phys_ActHbdg_PBCCurrIn_L))
               assignin("base",'VoltagOutR', out.phys_ActHbdg_PBCVOLIn_R);
               assignin("base",'CurrOutR',timeseries(out.phys_ActHbdg_PBCCurrIn_R));
             
               assignin("base",'ClampL',timeseries(out.phys_ActCal_PadForce_L));
               assignin("base",'ClampR',timeseries(out.phys_ActCal_PadForce_R));
               assignin("base",'APPRELEASE',out.AppRelease);
               
               assignin("base",'Faulty',timeseries(out.Faulty));
               Faulty=evalin('base','Faulty');
               APPRELEASE=evalin('base','APPRELEASE');
           
           
               global ret;
               
               %When simtime is very high(greater than 20 secondes) this function needs to be commented
               
               app.Gauge2.Value=0;
               for i=1:length(APPRELEASE.Data)
                    app.Gauge2.Value=APPRELEASE.Data(i);
                  ret= APPRELEASE.Data(i)
                       func(ret,app);
                    pause(0.00000005)
                end

               Alert=[];
               z=1;
             
               for i=1:1:39
                  if Faulty.Data(end,i)==66               %%Switch case selon la faute relevée depuis le block fault du MIL.
                       Alert(z)=i;
                       z=z+1;

                  end
                end


                assignin("base",'z',z); 
                assignin("base",'Alert',Alert);     

               
                 for x=1:1:z-1  
            
                 f=num2str(x);  
                 if    numel(Alert)==0
                break;
                 end
             
           switch  Alert(x)
                    case 1
                        disp('1') 
                     
                      
                        app.Lamp_13.Enable=true;
                    case 2
                        disp('2')
                       
                        app.Lamp_14.Enable=true;
                    case 3
                        disp('3')
                       
                        app.Lamp_12.Enable=true;
                    case 4
                        disp('4')
                       
                        app.Lamp_15.Enable=true;
                    case 5
                       
                        disp('5')
                        app.Lamp_16.Enable=true;
                    case 6
                        
                        disp('6')
                        app.Lamp_17.Enable=true;
                    case 7
                    
                        disp('7')
                        app.Lamp_18.Enable=true;
                    case 8
                     
                        disp('8')
                        app.Lamp_19.Enable=true;
                    case 9
                    
                        disp('9')
                        app.Lamp_20.Enable=true;
                    case 10
                      
                        disp('10')
                        app.Lamp_21.Enable=true;
                    case 11
                      
                        disp('11')
                        app.Lamp_23.Enable=true;
                    case 12
                       
                        disp('12')
                        app.Lamp_24.Enable=true;
                    case 13
                        
                        disp('13')
                        app.Lamp_25.Enable=true;
                    case 14
                        
                        disp('14')
                        app.Lamp_26.Enable=true;
                    case 15
                         
                      
                        disp('15')
                        app.Lamp_27.Enable=true;
                    case 16
                         
                  
                        disp('16')
                        app.Lamp_28.Enable=true;
                    case 17
                        
                       
                        disp('17')
                        app.Lamp_29.Enable=true;
                    case 18
                          
                      
                        disp('18')
                        app.Lamp_22.Enable=true;
                    case 19
                          
                        app.Lamp_38.Enable=true;
                        disp('19')
                    case 20
                       
                         app.Lamp_37.Enable=true;
                        disp('20')
                    case 21
                        
                       app.Lamp_36.Enable=true;
                        disp('21')
                    case 22
                        
                          app.Lamp_30.Enable=true;
                        disp('22')
                    case 23
                            app.Lamp_35.Enable=true;
                        disp('23')
                    case 24
                         app.Lamp_34.Enable=true;
                        disp('24')
                    case 25
                         app.Lamp_33.Enable=true;
                        disp('25')
                    case 26
                         app.Lamp_32.Enable=true;
                        disp('26')
                    case 27
                         app.Lamp_31.Enable=true;
                        disp('27')
                        
                    case 28
                         app.Lamp_47.Enable=true;
               
                        disp('28')
                    case 29
                         app.Lamp_46.Enable=true
                        disp('29')
                    case 30
                         app.Lamp_45.Enable=true
                        disp('30')
                    case 31
                         app.Lamp_39.Enable=true
                        disp('31')
                    case 32
                         app.Lamp_44.Enable=true
                        disp('32')
                    case 33
                         app.Lamp_43.Enable=true
                        disp('33')
                    case 34
                         app.Lamp_42.Enable=true
                        disp('34')
                    case 35
                         app.Lamp_41.Enable=true
                        disp('35')
                    case 36
                         app.Lamp_40.Enable=true
                        disp('36')
                    case 37
                        % app.Lamp_31.Enable=true
                        disp('37')
                    case 38
                        % app.Lamp_31.Enable=true
                        disp('38')
                    case 39
                        % app.Lamp_31.Enable=true
                        disp('39')
               otherwise
                        disp('nothing')
                        
           end
           
                 end
           
            z=z-1;     
            if (z==0)

          
          disp('no error to show');

           
           end
     
     
         end
    

               end                            
      
        
        end

        % Value changed function: ReleaseButton
        function ReleaseButtonValueChanged(app, event)

            set_param('APB_Model0_v03_Test','SimulationCommand','pause')  

            assignin("base",'calb_Driver_bttn',-1);
            Vlt=app.VoltageEditField.Value;
            Actl=app.ACT_INITLEditField.Value;
            Actr=app.ACT_INITREditField.Value;
            assignin("base",'phys_PwrNet_V_In',Vlt); 
            assignin("base",'calb_Driver_Prss',app.PressurebarKnob.Value);
            assignin("base",'calb_Driver_acce',app.Knob.Value);
            assignin("base",'ReleaseLimit',app.ReleaseLimitEditField.Value);
            assignin("base",'calb_PdsFcn_DSR9',Actr);
            assignin("base",'calb_PdsFcn_DSR1',Actl);
            assignin("base",'phys_RdEnvt_Slope',app.SlopeEditField.Value);
            assignin("base",'Instru_FreeTravelR',app.FreeTravelREditField.Value);
            assignin("base",'Instru_FreeTravelL',app.FreeTravelLEditField.Value);

            assignin("base",'calb_ActMtr_KMotL',app.KMOTEditField.Value);
            assignin("base",'calb_ActMtr_RMotR',app.RMOTEditField.Value);
            assignin("base",'Instru_HpsAckDelay',app.HPSDELAYEditField.Value);  
            assignin("base",'calb_HWDrCt_HstAvL',app.HstAvail_LEditField.Value);
            assignin("base",'calb_HWDrCt_HstAvR',app.HstAvail_REditField.Value);
          
            %Fault  1-2
            %assignin("base",'calb_PdsFcn_DSV1',0);
            %assignin("base",'calb_PdsFcn_DSV2',0);
            %assignin("base",'calb_PdsFcn_DSV3',0);
            assignin("base",'calb_VehBhv_Temp',app.TemperatureEditField.Value); 
           
            x=get_param('APB_Model0_v03_Test','SimulationTime')
            app.CurrTimeEditField.Value=x;
            set_param('APB_Model0_v03_Test', 'SimulationCommand', 'update')
            set_param('APB_Model0_v03_Test','SimulationCommand','continue')


        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
           
            set_param('APB_Model0_v03_Test','SimulationCommand','pause')  
           
           
            Vlt=app.VoltageEditField.Value;
            Actl=app.ACT_INITLEditField.Value;
            Actr=app.ACT_INITREditField.Value;
            assignin("base",'phys_PwrNet_V_In',Vlt); 
            assignin("base",'calb_Driver_Prss',app.PressurebarKnob.Value);
            assignin("base",'calb_Driver_acce',app.Knob.Value);
            assignin("base",'ReleaseLimit',app.ReleaseLimitEditField.Value);
            assignin("base",'calb_PdsFcn_DSR9',Actr);
            assignin("base",'calb_PdsFcn_DSR1',Actl);
            assignin("base",'Instru_FreeTravelR',app.FreeTravelREditField.Value);
            assignin("base",'Instru_FreeTravelL',app.FreeTravelLEditField.Value);
            assignin("base",'phys_RdEnvt_Slope',app.SlopeEditField.Value);
            assignin("base",'calb_ActMtr_KMotL',app.KMOTEditField.Value);
            assignin("base",'calb_ActMtr_RMotR',app.RMOTEditField.Value);
            assignin("base",'Instru_HpsAckDelay',app.HPSDELAYEditField.Value);  
            assignin("base",'calb_HWDrCt_HstAvL',app.HstAvail_LEditField.Value);
            assignin("base",'calb_HWDrCt_HstAvR',app.HstAvail_REditField.Value);
           
            %Fault 1-2
            %assignin("base",'calb_PdsFcn_DSV1',0);
            %assignin("base",'calb_PdsFcn_DSV2',0);
            %assignin("base",'calb_PdsFcn_DSV3',0);
            assignin("base",'calb_VehBhv_Temp',app.TemperatureEditField.Value); 
      
            x=get_param('APB_Model0_v03_Test','SimulationTime')
            app.CurrTimeEditField.Value=x;
                
            assignin("base",'calb_Driver_bttn',1);
            set_param('APB_Model0_v03_Test', 'SimulationCommand', 'update')
            set_param('APB_Model0_v03_Test','SimulationCommand','continue')
            
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Value changed function: PressurebarKnob
        function PressurebarKnobValueChanged(app, event)
            value = app.PressurebarKnob.Value;
            
        end

        % Value changed function: SlopeEditField
        function SlopeEditFieldValueChanged(app, event)
            value = app.SlopeEditField.Value;
            
        end

        % Value changed function: ACT_INITLEditField
        function ACT_INITLEditFieldValueChanged(app, event)
            value = app.ACT_INITLEditField.Value;
            
        end

        % Value changed function: SimTimeEditField
        function SimTimeEditFieldValueChanged(app, event)
            value = app.SimTimeEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                    
                 app.Lamp_13.Enable=false;
                 app.Lamp_14.Enable=false;
                 app.Lamp_12.Enable=false;
                 app.Lamp_15.Enable=false;
                 app.Lamp_16.Enable=false;
                 app.Lamp_17.Enable=false;
                 app.Lamp_18.Enable=false;
                 app.Lamp_19.Enable=false;
                 app.Lamp_20.Enable=false;

                 app.Lamp_21.Enable=false;
                 app.Lamp_23.Enable=false;
                 app.Lamp_24.Enable=false;
                 app.Lamp_25.Enable=false;
                 app.Lamp_26.Enable=false;
                 app.Lamp_27.Enable=false;
                 app.Lamp_28.Enable=false;
                 app.Lamp_29.Enable=false;
                 app.Lamp_22.Enable=false;
                 
                 app.Lamp_38.Enable=false;
                 app.Lamp_37.Enable=false;
                 app.Lamp_36.Enable=false;
                 app.Lamp_30.Enable=false;
                 app.Lamp_35.Enable=false;
                 app.Lamp_34.Enable=false;
                 app.Lamp_33.Enable=false;
                 app.Lamp_32.Enable=false;
                 app.Lamp_31.Enable=false;

                 app.Lamp_47.Enable=false;
                 app.Lamp_46.Enable=false;
                 app.Lamp_45.Enable=false;
                 app.Lamp_39.Enable=false;
                 app.Lamp_44.Enable=false;
                 app.Lamp_43.Enable=false;
                 app.Lamp_42.Enable=false;
                 app.Lamp_41.Enable=false;
                 app.Lamp_40.Enable=false;
                 
                 
            clear mex;
        end

        % Value changed function: FaultsinjectionEditField
        function FaultsinjectionEditFieldValueChanged(app, event)
            value = app.FaultsinjectionEditField.Value;
            
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
           
                     
            %%%% Stop simulation and assign    
            x=get_param('APB_Model0_v03_Test','SimulationTime')
            app.CurrTimeEditField.Value=x;
            set_param('APB_Model0_v03_Test','SimulationCommand','pause')
            
            Vlt=app.VoltageEditField.Value;
            Actl=app.ACT_INITLEditField.Value;
            Actr=app.ACT_INITREditField.Value;
            assignin("base",'phys_PwrNet_V_In',Vlt); 
            assignin("base",'calb_Driver_Prss',app.PressurebarKnob.Value);
            assignin("base",'calb_Driver_acce',app.Knob.Value);
            assignin("base",'ReleaseLimit',app.ReleaseLimitEditField.Value);
            assignin("base",'calb_PdsFcn_DSR9',Actr);
            assignin("base",'calb_PdsFcn_DSR1',Actl);
            assignin("base",'phys_RdEnvt_Slope',app.SlopeEditField.Value);
            assignin("base",'Instru_FreeTravelR',app.FreeTravelREditField.Value);
            assignin("base",'Instru_FreeTravelL',app.FreeTravelLEditField.Value);

            assignin("base",'calb_ActMtr_KMotL',app.KMOTEditField.Value);
            assignin("base",'calb_ActMtr_RMotR',app.RMOTEditField.Value);
            assignin("base",'Instru_HpsAckDelay',app.HPSDELAYEditField.Value);  
            assignin("base",'calb_HWDrCt_HstAvL',app.HstAvail_LEditField.Value);
            assignin("base",'calb_HWDrCt_HstAvR',app.HstAvail_REditField.Value);
            %assignin("base",'calb_PdsFcn_DSV1',0);
            %assignin("base",'calb_PdsFcn_DSV2',0);
            %assignin("base",'calb_PdsFcn_DSV3',0);
            assignin("base",'calb_VehBhv_Temp',app.TemperatureEditField.Value); 
            
            
            %%%%      Update then continue
            set_param('APB_Model0_v03_Test', 'SimulationCommand', 'update')
            set_param('APB_Model0_v03_Test','SimulationCommand','continue')

        end

        % Value changed function: CurrTimeEditField
        function CurrTimeEditFieldValueChanged(app, event)
            value = app.CurrTimeEditField.Value;
            
        end

        % Button pushed function: timeButton
        function timeButtonPushed(app, event)

                  %  rto = get_param(To Workspace13,'RuntimeObject');
           x=get_param('APB_Model0_v03_Test','SimulationTime')
            app.CurrTimeEditField.Value=x;
      
        end

        % Callback function
        function StopButtonPushed(app, event)
            set_param('APB_Model0_v03_Test','SimulationCommand','stop')
            pause(0.5)
               assignin("base",'VoltagOutL', out.phys_ActHbdg_PBCVOLIn_L);
               assignin("base",'CurrOutL',timeseries(out.phys_ActHbdg_PBCCurrIn_L))
               assignin("base",'VoltagOutR', out.phys_ActHbdg_PBCVOLIn_R);
               assignin("base",'CurrOutR',timeseries(out.phys_ActHbdg_PBCCurrIn_R));
             
               assignin("base",'ClampL',timeseries(out.phys_ActCal_PadForce_L));
               assignin("base",'ClampR',timeseries(out.phys_ActCal_PadForce_R));    
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
                     set_param('APB_Model0_v03_Test','SimulationCommand','stop')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SlpEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'cap.png';
            app.Gauge = uigauge(app.Panel, 'circular');
            app.Gauge2 = uigauge(app.Panel, 'circular');
            app.Label = uilabel(app.Panel);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.Lamp = uilamp(app.Panel);
            app.Lamp.Enable = 'off';
            app.Lamp_3Label = uilabel(app.Panel);
            app.Lamp_3Label.Enable = 'off';
            app.Lamp_3Label.Text = '';
            app.Lamp_3 = uilamp(app.Panel);
            app.Lamp_3.Enable = 'off';
            app.Lamp_2Label = uilabel(app.Panel);
            app.Lamp_2Label.Enable = 'off';
            app.Lamp_2Label.Text = '';
            app.Lamp_2 = uilamp(app.Panel);
            app.Lamp_2.Enable = 'off';
            app.Lamp_7 = uilamp(app.Panel);
            app.Lamp_7.Enable = 'off';
            app.PButton = uibutton(app.Panel, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.PressurebarKnobLabel = uilabel(app.Panel);
            app.PressurebarKnobLabel.Text = 'Pressure(bar)';
            app.PressurebarKnob = uiknob(app.Panel, 'continuous');
            app.PressurebarKnob.MajorTicks = [25 50 75];
            app.PressurebarKnob.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.PressurebarKnob.ValueChangedFcn = createCallbackFcn(app, @PressurebarKnobValueChanged, true);
            app.SlopeEditFieldLabel = uilabel(app.Panel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.Panel, 'numeric');
            app.SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeEditFieldValueChanged, true);
            app.VoltageEditFieldLabel = uilabel(app.Panel);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.Panel, 'numeric');
            app.ACT_INITREditFieldLabel = uilabel(app.Panel);
            app.ACT_INITREditFieldLabel.Text = 'ACT_INITR';
            app.ACT_INITREditField = uieditfield(app.Panel, 'numeric');
            app.ACT_INITLEditFieldLabel = uilabel(app.Panel);
            app.ACT_INITLEditFieldLabel.Text = 'ACT_INITL';
            app.ACT_INITLEditField = uieditfield(app.Panel, 'numeric');
            app.ACT_INITLEditField.ValueChangedFcn = createCallbackFcn(app, @ACT_INITLEditFieldValueChanged, true);
            app.Lamp_10Label = uilabel(app.Panel);
            app.Lamp_10Label.Enable = 'off';
            app.Lamp_10Label.Text = '';
            app.Lamp_10 = uilamp(app.Panel);
            app.Lamp_10.Enable = 'off';
            app.Lamp_11Label = uilabel(app.Panel);
            app.Lamp_11Label.Enable = 'off';
            app.Lamp_11Label.Text = '';
            app.Lamp_11 = uilamp(app.Panel);
            app.Lamp_11.Enable = 'off';
            app.SimTimeEditFieldLabel = uilabel(app.Panel);
            app.SimTimeEditFieldLabel.Text = 'SimTime';
            app.SimTimeEditField = uieditfield(app.Panel, 'numeric');
            app.SimTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimTimeEditFieldValueChanged, true);
            app.SIButton = uibutton(app.Panel, 'state');
            app.SIButton.ValueChangedFcn = createCallbackFcn(app, @SIButtonValueChanged, true);
            app.SIButton.Text = 'SI';
            app.Knob = uiknob(app.Panel, 'continuous');
            app.Knob.Limits = [0 1000];
            app.Knob.MajorTicks = [250 500 750];
            app.Accelerationms2Label = uilabel(app.Panel);
            app.Accelerationms2Label.Text = 'Acceleration(m/s^2)';
            app.ReleaseButton = uibutton(app.Panel, 'state');
            app.ReleaseButton.ValueChangedFcn = createCallbackFcn(app, @ReleaseButtonValueChanged, true);
            app.ReleaseButton.Text = 'Release';
            app.ApplyButton = uibutton(app.Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.FaultySignalsLabel = uilabel(app.Panel);
            app.FaultySignalsLabel.Text = '        Faulty Signals';
            app.Label_26 = uilabel(app.Panel);
            app.Label_26.Enable = 'off';
            app.Label_26.Text = '3';
            app.Lamp_12 = uilamp(app.Panel);
            app.Lamp_12.Enable = 'off';
            app.Lamp_15Label = uilabel(app.Panel);
            app.Lamp_15Label.Enable = 'off';
            app.Lamp_15Label.Text = '4';
            app.Lamp_15 = uilamp(app.Panel);
            app.Lamp_15.Enable = 'off';
            app.Lamp_16Label = uilabel(app.Panel);
            app.Lamp_16Label.Enable = 'off';
            app.Lamp_16Label.Text = '5';
            app.Lamp_16 = uilamp(app.Panel);
            app.Lamp_16.Enable = 'off';
            app.Lamp_17Label = uilabel(app.Panel);
            app.Lamp_17Label.Enable = 'off';
            app.Lamp_17Label.Text = '6';
            app.Lamp_17 = uilamp(app.Panel);
            app.Lamp_17.Enable = 'off';
            app.Lamp_18Label = uilabel(app.Panel);
            app.Lamp_18Label.Enable = 'off';
            app.Lamp_18Label.Text = '7';
            app.Lamp_18 = uilamp(app.Panel);
            app.Lamp_18.Enable = 'off';
            app.Lamp_19Label = uilabel(app.Panel);
            app.Lamp_19Label.Enable = 'off';
            app.Lamp_19Label.Text = '8';
            app.Lamp_19 = uilamp(app.Panel);
            app.Lamp_19.Enable = 'off';
            app.Lamp_14Label = uilabel(app.Panel);
            app.Lamp_14Label.Enable = 'off';
            app.Lamp_14Label.Text = '2';
            app.Lamp_14 = uilamp(app.Panel);
            app.Lamp_14.Enable = 'off';
            app.Lamp_13Label = uilabel(app.Panel);
            app.Lamp_13Label.Enable = 'off';
            app.Lamp_13Label.Text = '1';
            app.Lamp_13 = uilamp(app.Panel);
            app.Lamp_13.Enable = 'off';
            app.Lamp_20Label = uilabel(app.Panel);
            app.Lamp_20Label.Enable = 'off';
            app.Lamp_20Label.Text = '9';
            app.Lamp_20 = uilamp(app.Panel);
            app.Lamp_20.Enable = 'off';
            app.Label_27 = uilabel(app.Panel);
            app.Label_27.Enable = 'off';
            app.Label_27.Text = '10';
            app.Lamp_21 = uilamp(app.Panel);
            app.Lamp_21.Enable = 'off';
            app.Label_28 = uilabel(app.Panel);
            app.Label_28.Enable = 'off';
            app.Label_28.Text = '18';
            app.Lamp_22 = uilamp(app.Panel);
            app.Lamp_22.Enable = 'off';
            app.Label_32 = uilabel(app.Panel);
            app.Label_32.Enable = 'off';
            app.Label_32.Text = '11';
            app.Lamp_23 = uilamp(app.Panel);
            app.Lamp_23.Enable = 'off';
            app.Label_33 = uilabel(app.Panel);
            app.Label_33.Enable = 'off';
            app.Label_33.Text = '12';
            app.Lamp_24 = uilamp(app.Panel);
            app.Lamp_24.Enable = 'off';
            app.Label_34 = uilabel(app.Panel);
            app.Label_34.Enable = 'off';
            app.Label_34.Text = '13';
            app.Lamp_25 = uilamp(app.Panel);
            app.Lamp_25.Enable = 'off';
            app.Label_36 = uilabel(app.Panel);
            app.Label_36.Enable = 'off';
            app.Label_36.Text = '15';
            app.Lamp_27 = uilamp(app.Panel);
            app.Lamp_27.Enable = 'off';
            app.Label_37 = uilabel(app.Panel);
            app.Label_37.Enable = 'off';
            app.Label_37.Text = '16';
            app.Lamp_28 = uilamp(app.Panel);
            app.Lamp_28.Enable = 'off';
            app.Label_38 = uilabel(app.Panel);
            app.Label_38.Enable = 'off';
            app.Label_38.Text = '17';
            app.Lamp_29 = uilamp(app.Panel);
            app.Lamp_29.Enable = 'off';
            app.Label_35 = uilabel(app.Panel);
            app.Label_35.Enable = 'off';
            app.Label_35.Text = '14';
            app.Lamp_26 = uilamp(app.Panel);
            app.Lamp_26.Enable = 'off';
            app.Label_29 = uilabel(app.Panel);
            app.Label_29.Enable = 'off';
            app.Label_29.Text = '22';
            app.Lamp_30 = uilamp(app.Panel);
            app.Lamp_30.Enable = 'off';
            app.Lamp_35Label = uilabel(app.Panel);
            app.Lamp_35Label.Enable = 'off';
            app.Lamp_35Label.Text = '23';
            app.Lamp_35 = uilamp(app.Panel);
            app.Lamp_35.Enable = 'off';
            app.Lamp_34Label = uilabel(app.Panel);
            app.Lamp_34Label.Enable = 'off';
            app.Lamp_34Label.Text = '24';
            app.Lamp_34 = uilamp(app.Panel);
            app.Lamp_34.Enable = 'off';
            app.Lamp_32Label = uilabel(app.Panel);
            app.Lamp_32Label.Enable = 'off';
            app.Lamp_32Label.Text = '26';
            app.Lamp_32 = uilamp(app.Panel);
            app.Lamp_32.Enable = 'off';
            app.Lamp_31Label = uilabel(app.Panel);
            app.Lamp_31Label.Enable = 'off';
            app.Lamp_31Label.Text = '27';
            app.Lamp_31 = uilamp(app.Panel);
            app.Lamp_31.Enable = 'off';
            app.Lamp_36Label = uilabel(app.Panel);
            app.Lamp_36Label.Enable = 'off';
            app.Lamp_36Label.Text = '21';
            app.Lamp_36 = uilamp(app.Panel);
            app.Lamp_36.Enable = 'off';
            app.Lamp_37Label = uilabel(app.Panel);
            app.Lamp_37Label.Enable = 'off';
            app.Lamp_37Label.Text = '20';
            app.Lamp_37 = uilamp(app.Panel);
            app.Lamp_37.Enable = 'off';
            app.Lamp_38Label = uilabel(app.Panel);
            app.Lamp_38Label.Enable = 'off';
            app.Lamp_38Label.Text = '19';
            app.Lamp_38 = uilamp(app.Panel);
            app.Lamp_38.Enable = 'off';
            app.Lamp_33Label = uilabel(app.Panel);
            app.Lamp_33Label.Enable = 'off';
            app.Lamp_33Label.Text = '25';
            app.Lamp_33 = uilamp(app.Panel);
            app.Lamp_33.Enable = 'off';
            app.Label_30 = uilabel(app.Panel);
            app.Label_30.Enable = 'off';
            app.Label_30.Text = '31';
            app.Lamp_39 = uilamp(app.Panel);
            app.Lamp_39.Enable = 'off';
            app.Lamp_46Label = uilabel(app.Panel);
            app.Lamp_46Label.Enable = 'off';
            app.Lamp_46Label.Text = '29';
            app.Lamp_46 = uilamp(app.Panel);
            app.Lamp_46.Enable = 'off';
            app.Lamp_47Label = uilabel(app.Panel);
            app.Lamp_47Label.Enable = 'off';
            app.Lamp_47Label.Text = '28';
            app.Lamp_47 = uilamp(app.Panel);
            app.Lamp_47.Enable = 'off';
            app.Lamp_45Label = uilabel(app.Panel);
            app.Lamp_45Label.Enable = 'off';
            app.Lamp_45Label.Text = '30';
            app.Lamp_45 = uilamp(app.Panel);
            app.Lamp_45.Enable = 'off';
            app.Lamp_40Label = uilabel(app.Panel);
            app.Lamp_40Label.Enable = 'off';
            app.Lamp_40Label.Text = '36';
            app.Lamp_40 = uilamp(app.Panel);
            app.Lamp_40.Enable = 'off';
            app.Lamp_41Label = uilabel(app.Panel);
            app.Lamp_41Label.Enable = 'off';
            app.Lamp_41Label.Text = '35';
            app.Lamp_41 = uilamp(app.Panel);
            app.Lamp_41.Enable = 'off';
            app.Lamp_42Label = uilabel(app.Panel);
            app.Lamp_42Label.Enable = 'off';
            app.Lamp_42Label.Text = '34';
            app.Lamp_42 = uilamp(app.Panel);
            app.Lamp_42.Enable = 'off';
            app.Label_31 = uilabel(app.Panel);
            app.Label_31.Enable = 'off';
            app.Label_31.Text = '    33';
            app.Lamp_43 = uilamp(app.Panel);
            app.Lamp_43.Enable = 'off';
            app.Lamp_44Label = uilabel(app.Panel);
            app.Lamp_44Label.Enable = 'off';
            app.Lamp_44Label.Text = '32';
            app.Lamp_44 = uilamp(app.Panel);
            app.Lamp_44.Enable = 'off';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'tytytytyty.png';
            app.HstAvail_LEditFieldLabel = uilabel(app.Panel);
            app.HstAvail_LEditFieldLabel.Text = 'Hst Avail_L';
            app.HstAvail_LEditField = uieditfield(app.Panel, 'numeric');
            app.HstAvail_REditFieldLabel = uilabel(app.Panel);
            app.HstAvail_REditFieldLabel.Text = 'Hst Avail_R';
            app.HstAvail_REditField = uieditfield(app.Panel, 'numeric');
            app.KMOTEditFieldLabel = uilabel(app.Panel);
            app.KMOTEditFieldLabel.Text = 'KMOT';
            app.KMOTEditField = uieditfield(app.Panel, 'numeric');
            app.PBCStateLabel = uilabel(app.Panel);
            app.PBCStateLabel.Text = 'PBC State: ';
            app.Switch_3 = uiswitch(app.Panel, 'toggle');
            app.Switch_3.Items = {'Left/Right', ''};
            app.Switch_3.Value = 'Left/Right';
            app.PlotGraphLabel = uilabel(app.Panel);
            app.PlotGraphLabel.Text = 'Plot Graph';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Switch_4 = uiswitch(app.Panel, 'rocker');
            app.Switch_4.Items = {'Off', ''};
            app.FaultsinjectionEditFieldLabel = uilabel(app.Panel);
            app.FaultsinjectionEditFieldLabel.Text = 'Faults injection';
            app.FaultsinjectionEditField = uieditfield(app.Panel, 'text');
            app.FaultsinjectionEditField.ValueChangedFcn = createCallbackFcn(app, @FaultsinjectionEditFieldValueChanged, true);
            app.TestmodeLabel = uilabel(app.Panel);
            app.TestmodeLabel.Text = 'Test mode';
            app.RMOTEditFieldLabel = uilabel(app.Panel);
            app.RMOTEditFieldLabel.Text = 'RMOT';
            app.RMOTEditField = uieditfield(app.Panel, 'numeric');
            app.HPSDELAYEditFieldLabel = uilabel(app.Panel);
            app.HPSDELAYEditFieldLabel.Text = 'HPSDELAY';
            app.HPSDELAYEditField = uieditfield(app.Panel, 'numeric');
            app.TemperatureEditFieldLabel = uilabel(app.Panel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.Panel, 'numeric');
            app.ReleaseLimitEditFieldLabel = uilabel(app.Panel);
            app.ReleaseLimitEditFieldLabel.Text = 'ReleaseLimit';
            app.ReleaseLimitEditField = uieditfield(app.Panel, 'numeric');
            app.CurrTimeEditFieldLabel = uilabel(app.Panel);
            app.CurrTimeEditFieldLabel.Text = 'CurrTime';
            app.CurrTimeEditField = uieditfield(app.Panel, 'numeric');
            app.CurrTimeEditField.ValueChangedFcn = createCallbackFcn(app, @CurrTimeEditFieldValueChanged, true);
            app.UButton = uibutton(app.Panel, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.UpdateParamLabel = uilabel(app.Panel);
            app.UpdateParamLabel.Text = 'Update Param';
            app.timeButton = uibutton(app.Panel, 'push');
            app.timeButton.ButtonPushedFcn = createCallbackFcn(app, @timeButtonPushed, true);
            app.timeButton.Text = 'time';
            app.FreeTravelLEditFieldLabel = uilabel(app.Panel);
            app.FreeTravelLEditFieldLabel.Text = 'FreeTravelL';
            app.FreeTravelLEditField = uieditfield(app.Panel, 'numeric');
            app.FreeTravelREditFieldLabel = uilabel(app.Panel);
            app.FreeTravelREditFieldLabel.Text = 'FreeTravelR';
            app.FreeTravelREditField = uieditfield(app.Panel, 'numeric');
            app.SButton = uibutton(app.Panel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.StopLabel = uilabel(app.Panel);
            app.StopLabel.Text = 'Stop';
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'CurrIn & Vin intensity')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 40];
            app.UIAxes.YLim = [-24 24];
            app.UIAxes.YTick = [-24 -7 7 24];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.Panel);
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Clamping Force(KN) Curve')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [0 25000];
            app.UIAxes2.YTick = [8300 16000 25000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HMI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end